classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ABBIRB120UIFigure            matlab.ui.Figure
        zLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        camposZ                      matlab.ui.control.Slider
        camposY                      matlab.ui.control.Slider
        camposX                      matlab.ui.control.Slider
        q1Label_2                    matlab.ui.control.Label
        ANGULOSEULERLabel            matlab.ui.control.Label
        euler3                       matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        euler2                       matlab.ui.control.NumericEditField
        yEditField_2Label_2          matlab.ui.control.Label
        euler1                       matlab.ui.control.NumericEditField
        xEditField_2Label_2          matlab.ui.control.Label
        sequenceEuler                matlab.ui.control.DropDown
        SecuenciaLabel               matlab.ui.control.Label
        slider_q6                    matlab.ui.control.Slider
        q6Label                      matlab.ui.control.Label
        slider_q5                    matlab.ui.control.Slider
        q5Label                      matlab.ui.control.Label
        slider_q4                    matlab.ui.control.Slider
        q4Label                      matlab.ui.control.Label
        slider_q3                    matlab.ui.control.Slider
        q3Label                      matlab.ui.control.Label
        slider_q2                    matlab.ui.control.Slider
        q2Label                      matlab.ui.control.Label
        slider_q1                    matlab.ui.control.Slider
        q1Label                      matlab.ui.control.Label
        COORDENADASARTICULARESLabel  matlab.ui.control.Label
        CalcularButton_directa       matlab.ui.control.Button
        EditField_q6                 matlab.ui.control.NumericEditField
        EditField_q5                 matlab.ui.control.NumericEditField
        EditField_q4                 matlab.ui.control.NumericEditField
        EditField_q3                 matlab.ui.control.NumericEditField
        EditField_q2                 matlab.ui.control.NumericEditField
        EditField_q1                 matlab.ui.control.NumericEditField
        POSICINCMARALabel            matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        MATRIZDETRANSFORMACINHOMOGNEALabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        COORDENADAScmLabel           matlab.ui.control.Label
        CalcularButton_inversa       matlab.ui.control.Button
        zEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        ABBIRB120Label               matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        plot                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        irb120
    end
    
 
    methods (Access = private)
        function plotRobot(app, q_rad)
            %Resetamos el elemento Axes, llamado 'app.plot'
            cla (app.plot, 'reset');
            %Creamos una figura invisible donde se graficará nuestro robot
            %con la función plot de la clase 'SerialLink' de la
            %Robotics Toolbox de Peter Corke
            fig = figure ('visible', 'off');
            show (app.irb120, q_rad)   
            axis auto;
            %Obtenemos la posicion de la camara de la figura, con el
            %comando 'campos'
            cameraSettings = campos;
            % Copiamos la figura en un App axes y guardamos
            % el manejador del nuevo objeto creado
            IFAxes = gca(fig);  
            % Copiamos el nuevo objeto IFAxes, en nuestro app.plot
            newPatchObjects = copyobj(IFAxes.Children, app.plot);  
            %Fijamos los ajustes de cámara
            app.plot.Projection = 'perspective';
            app.plot.CameraPosition =  cameraSettings;
            %Borramos la figura
            delete (fig)
            updateSliderCampos (app, cameraSettings)
        end
        
        function plotRobotSpecificCampos(app, q_deg, camera_postion)
            %Resetamos el elemento Axes, llamado 'app.plot'
            cla (app.plot, 'reset');
            %Creamos una figura invisible donde se graficará nuestro robot
            %con la función plot de la clase 'SerialLink' de la
            %Robotics Toolbox de Peter Corke
            fig = figure ('visible', 'off');
            show (app.irb120, deg2rad (q_deg))          
            % Copiamos la figura en un App axes y guardamos
            % el manejador del nuevo objeto creado
            IFAxes = gca(fig);  
            % Copiamos el nuevo objeto IFAxes, en nuestro app.plot
            newPatchObjects = copyobj(IFAxes.Children, app.plot);  
            %Fijamos los ajustes de cámara
            app.plot.Projection = 'perspective';
            app.plot.CameraPosition =  camera_postion;
            %Borramos la figura
            delete (fig)
        end
        
        function  updateAllEditFieldsQ(app, q_rad)
            q = rad2deg (q_rad);
            app.EditField_q1.Value = q(1);
            app.EditField_q2.Value = q(2);
            app.EditField_q3.Value = q(3);
            app.EditField_q4.Value = q(4);
            app.EditField_q5.Value = q(5);
            app.EditField_q6.Value = q(6);
        end
        
        function  updateAllSlidersQ (app, q_rad)
            q = rad2deg (q_rad);
            app.slider_q1.Value = q(1);
            app.slider_q2.Value = q(2);
            app.slider_q3.Value = q(3);
            app.slider_q4.Value = q(4);
            app.slider_q5.Value = q(5);
            app.slider_q6.Value = q(6);
        end
        
        function  updateXYZ (app, MTH)
            %trvec = tform2trvec(MTH)*100
            app.xEditField.Value = MTH (1,4)*100;
            app.yEditField.Value = MTH (2,4)*100;
            app.zEditField.Value = MTH (3,4)*100;   
        end

        function  updateEuler (app, MTH)
            euler_rad = tform2eul(MTH);   %by default the sequence is ZYX
            euler = rad2deg (euler_rad);
            app.euler1.Value = euler(1);
            app.euler2.Value = euler(2);
            app.euler3.Value = euler(3);   
            app.sequenceEuler.Value = "ZYX";
        end

        function  updateSliderCampos (app, camera_position)
            app.camposX.Value = camera_position(1);
            app.camposY.Value = camera_position(2);
            app.camposZ.Value = camera_position(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Define robot
           app.irb120=loadrobot ('abbIrb120T',"DataFormat","column");
           
           %Definimos parametros iniciales
           q0  =homeConfiguration(app.irb120);
           %Calculamos la cinematica directa
           MTH0 = getTransform(app.irb120, q0, 'tool0');

           %Update editField and sliders with new configuration
           updateAllEditFieldsQ(app, q0);
           updateAllSlidersQ (app, q0);
           %Update TCP's positon
           updateXYZ (app, MTH0);
           %Update TCP's orietation
           updateEuler (app, MTH0);
           %Plot robot
           plotRobot(app, q0);
           %Update MTH
           app.UITable.Data  = MTH0;      

        end

        % Value changed function: EditField_q1
        function EditField_q1ValueChanged(app, event)
            value = app.EditField_q1.Value;
            app.slider_q1.Value = value;
        end

        % Value changed function: slider_q1
        function slider_q1ValueChanged(app, event)
            value = app.slider_q1.Value;
            app.EditField_q1.Value = value;
        end

        % Value changing function: slider_q1
        function slider_q1ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q1.Value = changingValue;
        end

        % Value changed function: EditField_q2
        function EditField_q2ValueChanged(app, event)
            value = app.EditField_q2.Value;
            app.slider_q2.Value = value;            
        end

        % Value changing function: slider_q2
        function slider_q2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q2.Value = changingValue;            
        end

        % Value changed function: slider_q2
        function slider_q2ValueChanged(app, event)
            value = app.slider_q2.Value;
            app.EditField_q2.Value = value;            
        end

        % Value changed function: EditField_q3
        function EditField_q3ValueChanged(app, event)
            value = app.EditField_q3.Value;
            app.slider_q3.Value = value;            
        end

        % Value changed function: slider_q3
        function slider_q3ValueChanged(app, event)
            value = app.slider_q3.Value;
            app.EditField_q3.Value = value;            
        end

        % Value changing function: slider_q3
        function slider_q3ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q3.Value = changingValue;           
            
        end

        % Value changed function: EditField_q4
        function EditField_q4ValueChanged(app, event)
            value = app.EditField_q4.Value;
            app.slider_q4.Value = value;            
        end

        % Value changed function: slider_q4
        function slider_q4ValueChanged(app, event)
            value = app.slider_q4.Value;
            app.EditField_q4.Value = value;            
        end

        % Value changing function: slider_q4
        function slider_q4ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q4.Value = changingValue;            
        end

        % Value changed function: EditField_q5
        function EditField_q5ValueChanged(app, event)
            value = app.EditField_q5.Value;
            app.slider_q5.Value = value;
        end

        % Value changed function: slider_q5
        function slider_q5ValueChanged(app, event)
            value = app.slider_q5.Value;
            app.EditField_q5.Value = value;            
        end

        % Value changing function: slider_q5
        function slider_q5ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q5.Value = changingValue;            
        end

        % Value changed function: EditField_q6
        function EditField_q6ValueChanged(app, event)
            value = app.EditField_q6.Value;
            app.slider_q6.Value = value;            
        end

        % Value changed function: slider_q6
        function slider_q6ValueChanged(app, event)
            value = app.slider_q6.Value;
            app.EditField_q6.Value = value;            
        end

        % Value changing function: slider_q6
        function slider_q6ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_q6.Value = changingValue;        
        end

        % Button pushed function: CalcularButton_inversa
        function CalcularButton_inversaPushed(app, event)
            %Get values from UI
            valueX = app.xEditField.Value/100;
            valueY = app.yEditField.Value/100;
            valueZ = app.zEditField.Value/100;
            trvec = [valueX  valueY valueZ];

            euler1 = deg2rad(app.euler1.Value);
            euler2 = deg2rad(app.euler2.Value);
            euler3 = deg2rad(app.euler3.Value);
            euler = [euler1, euler2, euler3];
            sequence = app.sequenceEuler.Value;

            %Inverse kinematics
            MTH = trvec2tform (trvec)*eul2tform (euler , sequence); 
            ik = inverseKinematics('RigidBodyTree',app.irb120);
            weights = [0.25 0.25 0.25 1 1 1];
            initialguess = app.irb120.homeConfiguration;           
            [configSoln,solnInfo] = ik('tool0',MTH,weights,initialguess);

            %Update editField and sliders with new configurationconfigSoln
            updateAllEditFieldsQ(app, configSoln);
            updateAllSlidersQ (app, configSoln);
            %Plot robot
            plotRobot(app, configSoln);
            %Update MTH
            app.UITable.Data  = MTH;       
        end

        % Button pushed function: CalcularButton_directa
        function CalcularButton_directaPushed(app, event)
            %Get data from UI
            q  =[app.EditField_q1.Value; app.EditField_q2.Value; app.EditField_q3.Value; app.EditField_q4.Value; app.EditField_q5.Value; app.EditField_q6.Value];
           
           %Direct kinematics
           MTH = getTransform(app.irb120, deg2rad(q), 'tool0');

           %Update TCP's positon
           updateXYZ (app, MTH);
           %Update TCP's orietation
           updateEuler (app, MTH);
           %Plot robot
           plotRobot(app, q);
           %Update MTH
           app.UITable.Data  = MTH;      

        end

        % Callback function
        function xEditField_cameraValueChanged(app, event)
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            cameraSettings =   [app.xEditField.Value, app.yEditField_camera.Value, app.zEditField_camera.Value];
            plotRobotSpecificCampos (app, q, cameraSettings)
        end

        % Callback function
        function yEditField_cameraValueChanged(app, event)
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            cameraSettings =   [app.xEditField.Value, app.yEditField_camera.Value, app.zEditField_camera.Value];
            plotRobotSpecificCampos (app, q, cameraSettings)         
        end

        % Callback function
        function zEditField_cameraValueChanged(app, event)
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            cameraSettings =   [app.xEditField.Value, app.yEditField_camera.Value, app.zEditField_camera.Value];
            plotRobotSpecificCampos (app, q, cameraSettings)
        end

        % Value changed function: camposX
        function camposXValueChanged(app, event)
            cameraSettings = [app.camposX.Value, app.camposY.Value, app.camposZ.Value];
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            plotRobotSpecificCampos (app, q, cameraSettings)
        end

        % Value changed function: camposY
        function camposYValueChanged(app, event)
            cameraSettings = [app.camposX.Value, app.camposY.Value, app.camposZ.Value];
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            plotRobotSpecificCampos (app, q, cameraSettings)          
        end

        % Value changed function: camposZ
        function camposZValueChanged(app, event)
            cameraSettings = [app.camposX.Value, app.camposY.Value, app.camposZ.Value];
            q  =[app.EditField_q1.Value, app.EditField_q2.Value,app.EditField_q3.Value, app.EditField_q4.Value, app.EditField_q5.Value, app.EditField_q6.Value]';
            plotRobotSpecificCampos (app, q, cameraSettings)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ABBIRB120UIFigure = uifigure('Visible', 'off');
            app.ABBIRB120UIFigure.Name = 'ABB IRB120';
            app.plot = uiaxes(app.ABBIRB120UIFigure);
            app.plot.XTick = [];
            app.plot.YTick = [];
            app.Image4 = uiimage(app.ABBIRB120UIFigure);
            app.Image4.ImageSource = 'Color1.PNG';
            app.Image3 = uiimage(app.ABBIRB120UIFigure);
            app.Image3.ImageSource = 'Color3.png';
            app.ABBIRB120Label = uilabel(app.ABBIRB120UIFigure);
            app.ABBIRB120Label.Text = 'ABB IRB120';
            app.xEditFieldLabel = uilabel(app.ABBIRB120UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%11.2g';
            app.yEditFieldLabel = uilabel(app.ABBIRB120UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%11.2g';
            app.zEditFieldLabel = uilabel(app.ABBIRB120UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%11.2g';
            app.CalcularButton_inversa = uibutton(app.ABBIRB120UIFigure, 'push');
            app.CalcularButton_inversa.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_inversaPushed, true);
            app.CalcularButton_inversa.Text = {'Calcular'; 'cinemática'; 'inversa'};
            app.COORDENADAScmLabel = uilabel(app.ABBIRB120UIFigure);
            app.COORDENADAScmLabel.Text = 'COORDENADAS (cm)';
            app.UITable = uitable(app.ABBIRB120UIFigure);
            app.UITable.ColumnName = {'n'; 'o'; 'a'; 't'};
            app.UITable.RowName = {};
            app.MATRIZDETRANSFORMACINHOMOGNEALabel = uilabel(app.ABBIRB120UIFigure);
            app.MATRIZDETRANSFORMACINHOMOGNEALabel.Text = 'MATRIZ DE TRANSFORMACIÓN HOMOGÉNEA';
            app.Image2 = uiimage(app.ABBIRB120UIFigure);
            app.Image2.ImageSource = 'Color3.png';
            app.POSICINCMARALabel = uilabel(app.ABBIRB120UIFigure);
            app.POSICINCMARALabel.Text = 'POSICIÓN CÁMARA';
            app.EditField_q1 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q1.ValueChangedFcn = createCallbackFcn(app, @EditField_q1ValueChanged, true);
            app.EditField_q2 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q2.ValueChangedFcn = createCallbackFcn(app, @EditField_q2ValueChanged, true);
            app.EditField_q3 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q3.ValueChangedFcn = createCallbackFcn(app, @EditField_q3ValueChanged, true);
            app.EditField_q4 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q4.ValueChangedFcn = createCallbackFcn(app, @EditField_q4ValueChanged, true);
            app.EditField_q5 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q5.ValueChangedFcn = createCallbackFcn(app, @EditField_q5ValueChanged, true);
            app.EditField_q6 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.EditField_q6.ValueChangedFcn = createCallbackFcn(app, @EditField_q6ValueChanged, true);
            app.CalcularButton_directa = uibutton(app.ABBIRB120UIFigure, 'push');
            app.CalcularButton_directa.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_directaPushed, true);
            app.CalcularButton_directa.Text = {'Calcular '; 'cinemática'; 'directa'};
            app.COORDENADASARTICULARESLabel = uilabel(app.ABBIRB120UIFigure);
            app.COORDENADASARTICULARESLabel.Text = {'COORDENADAS'; 'ARTICULARES (º)'};
            app.q1Label = uilabel(app.ABBIRB120UIFigure);
            app.q1Label.Text = 'q1';
            app.slider_q1 = uislider(app.ABBIRB120UIFigure);
            app.slider_q1.Limits = [-165 165];
            app.slider_q1.MajorTicks = [-165 0 165];
            app.slider_q1.ValueChangedFcn = createCallbackFcn(app, @slider_q1ValueChanged, true);
            app.slider_q1.ValueChangingFcn = createCallbackFcn(app, @slider_q1ValueChanging, true);
            app.q2Label = uilabel(app.ABBIRB120UIFigure);
            app.q2Label.Text = 'q2';
            app.slider_q2 = uislider(app.ABBIRB120UIFigure);
            app.slider_q2.Limits = [-110 110];
            app.slider_q2.MajorTicks = [-110 0 110];
            app.slider_q2.ValueChangedFcn = createCallbackFcn(app, @slider_q2ValueChanged, true);
            app.slider_q2.ValueChangingFcn = createCallbackFcn(app, @slider_q2ValueChanging, true);
            app.q3Label = uilabel(app.ABBIRB120UIFigure);
            app.q3Label.Text = 'q3';
            app.slider_q3 = uislider(app.ABBIRB120UIFigure);
            app.slider_q3.Limits = [-110 70];
            app.slider_q3.MajorTicks = [-110 0 70];
            app.slider_q3.ValueChangedFcn = createCallbackFcn(app, @slider_q3ValueChanged, true);
            app.slider_q3.ValueChangingFcn = createCallbackFcn(app, @slider_q3ValueChanging, true);
            app.q4Label = uilabel(app.ABBIRB120UIFigure);
            app.q4Label.Text = 'q4';
            app.slider_q4 = uislider(app.ABBIRB120UIFigure);
            app.slider_q4.Limits = [-160 160];
            app.slider_q4.MajorTicks = [-160 0 160];
            app.slider_q4.ValueChangedFcn = createCallbackFcn(app, @slider_q4ValueChanged, true);
            app.slider_q4.ValueChangingFcn = createCallbackFcn(app, @slider_q4ValueChanging, true);
            app.q5Label = uilabel(app.ABBIRB120UIFigure);
            app.q5Label.Text = 'q5';
            app.slider_q5 = uislider(app.ABBIRB120UIFigure);
            app.slider_q5.Limits = [-120 120];
            app.slider_q5.MajorTicks = [-120 0 120];
            app.slider_q5.ValueChangedFcn = createCallbackFcn(app, @slider_q5ValueChanged, true);
            app.slider_q5.ValueChangingFcn = createCallbackFcn(app, @slider_q5ValueChanging, true);
            app.q6Label = uilabel(app.ABBIRB120UIFigure);
            app.q6Label.Text = 'q6';
            app.slider_q6 = uislider(app.ABBIRB120UIFigure);
            app.slider_q6.Limits = [-400 400];
            app.slider_q6.MajorTicks = [-400 0 400];
            app.slider_q6.ValueChangedFcn = createCallbackFcn(app, @slider_q6ValueChanged, true);
            app.slider_q6.ValueChangingFcn = createCallbackFcn(app, @slider_q6ValueChanging, true);
            app.SecuenciaLabel = uilabel(app.ABBIRB120UIFigure);
            app.SecuenciaLabel.Text = 'Secuencia';
            app.sequenceEuler = uidropdown(app.ABBIRB120UIFigure);
            app.sequenceEuler.Items = {'ZYX', 'ZYZ', 'XYZ'};
            app.sequenceEuler.Value = 'ZYX';
            app.xEditField_2Label_2 = uilabel(app.ABBIRB120UIFigure);
            app.xEditField_2Label_2.Text = 'φ';
            app.euler1 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.euler1.ValueDisplayFormat = '%11.2g';
            app.yEditField_2Label_2 = uilabel(app.ABBIRB120UIFigure);
            app.yEditField_2Label_2.Text = {'θ'; ''};
            app.euler2 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.euler2.ValueDisplayFormat = '%11.2g';
            app.Label = uilabel(app.ABBIRB120UIFigure);
            app.Label.Text = ' ψ ';
            app.euler3 = uieditfield(app.ABBIRB120UIFigure, 'numeric');
            app.euler3.ValueDisplayFormat = '%11.2g';
            app.ANGULOSEULERLabel = uilabel(app.ABBIRB120UIFigure);
            app.ANGULOSEULERLabel.Text = 'ANGULOS EULER (º)';
            app.q1Label_2 = uilabel(app.ABBIRB120UIFigure);
            app.q1Label_2.Text = '';
            app.camposX = uislider(app.ABBIRB120UIFigure);
            app.camposX.Limits = [0 60];
            app.camposX.MajorTicks = [0 20 40 60];
            app.camposX.MajorTickLabels = {'0', '20', '40', '60'};
            app.camposX.ValueChangedFcn = createCallbackFcn(app, @camposXValueChanged, true);
            app.camposY = uislider(app.ABBIRB120UIFigure);
            app.camposY.Limits = [0 60];
            app.camposY.MajorTicks = [0 20 40 60];
            app.camposY.MajorTickLabels = {'0', '20', '40', '60'};
            app.camposY.ValueChangedFcn = createCallbackFcn(app, @camposYValueChanged, true);
            app.camposZ = uislider(app.ABBIRB120UIFigure);
            app.camposZ.Limits = [0 60];
            app.camposZ.MajorTicks = [0 20 40 60];
            app.camposZ.MajorTickLabels = {'0', '20', '40', '60'};
            app.camposZ.ValueChangedFcn = createCallbackFcn(app, @camposZValueChanged, true);
            app.xLabel = uilabel(app.ABBIRB120UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.ABBIRB120UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.ABBIRB120UIFigure);
            app.zLabel.Text = 'z';
            % Show the figure after all components are created
            app.ABBIRB120UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ABBIRB120UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ABBIRB120UIFigure)
        end
    end
end